classdef PL_FitApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        upSliderLabel             matlab.ui.control.Label
        upSlider                  matlab.ui.control.Slider
        lowSliderLabel            matlab.ui.control.Label
        lowSlider                 matlab.ui.control.Slider
        Label_2                   matlab.ui.control.Label
        WL_v2                     matlab.ui.control.NumericEditField
        Label                     matlab.ui.control.Label
        WL_v1                     matlab.ui.control.NumericEditField
        SliderLabel               matlab.ui.control.Label
        Slider                    matlab.ui.control.Slider
        Slider_4Label             matlab.ui.control.Label
        Slider_4                  matlab.ui.control.Slider
        ImportButton              matlab.ui.control.Button
        CenterWLnmEditFieldLabel  matlab.ui.control.Label
        CenterWLnmEditField       matlab.ui.control.NumericEditField
        EditField_3Label          matlab.ui.control.Label
        Time_v2                   matlab.ui.control.NumericEditField
        EditField_4Label          matlab.ui.control.Label
        Time_v1                   matlab.ui.control.NumericEditField
        UIAxes_2                  matlab.ui.control.UIAxes
        IRFButton                 matlab.ui.control.Button
        upSlider_3Label           matlab.ui.control.Label
        upSlider_3                matlab.ui.control.Slider
        lowSlider_2Label          matlab.ui.control.Label
        lowSlider_2               matlab.ui.control.Slider
        Label_3                   matlab.ui.control.Label
        WL_v2_2                   matlab.ui.control.NumericEditField
        Label_4                   matlab.ui.control.Label
        WL_v1_2                   matlab.ui.control.NumericEditField
        Slider_2Label             matlab.ui.control.Label
        Slider_2                  matlab.ui.control.Slider
        Slider_3Label             matlab.ui.control.Label
        Slider_3                  matlab.ui.control.Slider
        EditField_3Label_2        matlab.ui.control.Label
        Time_v2_2                 matlab.ui.control.NumericEditField
        EditField_4Label_2        matlab.ui.control.Label
        Time_v1_2                 matlab.ui.control.NumericEditField
        UIAxes3                   matlab.ui.control.UIAxes
        FitTable                  matlab.ui.control.Table
        NooftauSpinnerLabel       matlab.ui.control.Label
        NooftauSpinner            matlab.ui.control.Spinner
        bestTable                 matlab.ui.control.Table
        ExportButton              matlab.ui.control.Button
        TimeRangeDropDownLabel    matlab.ui.control.Label
        TimeRangeDropDown         matlab.ui.control.DropDown
        Slider_5Label             matlab.ui.control.Label
        y_offset                  matlab.ui.control.Slider
        ButtonGroup               matlab.ui.container.ButtonGroup
        linearButton              matlab.ui.control.RadioButton
        log10Button               matlab.ui.control.RadioButton
    end

    
    properties (Access = public)
        DataFileName % filename of data
        IrfFileName % instrumental function of data
        Data_z % experimental data
        Irf_z % irf data
        Data_TPR % time profile (cut by ROI)
        IRF_TPR % time profile of ird (cut by ROI)
        taus % for fitting, initial values
        FitData % tables for output
        copyme % fit results, and data sets
        
    end
    
    methods (Access = public)
        function Ax1(app)
            plotData(app);
            plotROI(app);
            Ax3(app);
        end
        
        function Ax2(app)
            plotData_irf(app);
            plotROI_irf(app);
            Ax3(app);
        end
        
        function Ax3(app)
            if isempty(app.DataFileName) || isempty(app.IrfFileName)
                return;
            end
            baseT = time(app);
            basewl = wl(app);
            
            % load experiments
            
            x1 = app.lowSlider.Value;
            x2 = app.upSlider.Value;
            y1 = app.Slider.Value;
            y2 = app.Slider_4.Value;
            szT = [1 size(app.Data_z,1)];% time (y)
            szW = [1 size(app.Data_z,2)];% wavelength (x)
            
            xI1 = (min(szW) * (100-x1) + max(szW) * x1)/100;
            xI2 = (min(szW) * (100-x2) + max(szW) * x2)/100;
            yI1 = (min(szT) * y1 + max(szT) * (100-y1))/100;
            yI2 = (min(szT) * y2 + max(szT) * (100-y2))/100;
            xr = sort(round([xI1 xI2]));
            yr = sort(round([yI1 yI2]));
            app.Data_TPR = app.Data_z(yr(1):yr(2),xr(1):xr(2));
            yd = sum(app.Data_TPR,2);
            xd = baseT(yr(1):yr(2));
            
            %load instrumental functions
            x1irf = app.lowSlider_2.Value;
            x2irf = app.upSlider_3.Value;
            y1irf = app.Slider_2.Value;
            y2irf = app.Slider_3.Value;
            szTirf = [1 size(app.Data_z,1)];% time (y)
            szWirf = [1 size(app.Data_z,2)];% wavelength (x)
            
            xI1irf = (min(szWirf) * (100-x1irf) + max(szWirf) * x1irf)/100;
            xI2irf = (min(szWirf) * (100-x2irf) + max(szWirf) * x2irf)/100;
            yI1irf = (min(szTirf) * y1irf + max(szTirf) * (100-y1irf))/100;
            yI2irf = (min(szTirf) * y2irf + max(szTirf) * (100-y2irf))/100;
            xr2 = sort(round([xI1irf xI2irf]));
            yr2 = sort(round([yI1irf yI2irf]));
            app.IRF_TPR = app.Irf_z(yr2(1):yr2(2),xr2(1):xr2(2));
            yirf = sum(app.IRF_TPR,2);
            xirf = baseT(yr2(1):yr2(2));
            % fitting and plot
            hold(app.UIAxes3,'off');
            semilogy(app.UIAxes3, xd, yd,'.',...
                xirf,yirf/max(yirf)*max(yd),'.');
            hold(app.UIAxes3,'on');
            ym = ylim(app.UIAxes3);
            baseline = 10^((log10(ym(1)) * (100-app.y_offset.Value) + log10(ym(2)) * app.y_offset.Value)/100);
            [bestTau, bestA, yFit] = ExponFitting(app.taus,xd,yd-baseline,yirf/max(yirf));
            yFit = yFit + baseline;
            % plot results
            semilogy(app.UIAxes3,...  %app.UIAxes3, xd, yd,'.',...%xirf,yirf/max(yirf)*max(yd),'.',...
                xd, yFit, '-',...
                baseT([1,end]),[baseline, baseline],'-');
            xlim(app.UIAxes3,[-Inf Inf]);
            
            
            % get paramaters and data
            best = [bestTau, bestA];
            app.bestTable.Data = best;
            app.copyme = zeros(length(baseT),5);
            app.copyme(:,1) = reshape(baseT,[],1);% time
            app.copyme(yr(1):yr(2),2) = reshape(yd,[],1);% data
            app.copyme(yr2(1):yr2(2),3) = reshape(yirf,[],1); %irf
            app.copyme(yr(1):yr(2),4) = reshape(yFit,[],1);% best fitting
            app.copyme(:,5) = baseline;%baseline
            T = table(app.copyme(:,1),app.copyme(:,2),app.copyme(:,3),...
                app.copyme(:,4),app.copyme(:,5));
            T.Properties.VariableNames = {'time', 'data', 'irf', 'fit','baseline'};
            app.FitData = T;
            
            
        end
        
%         function z = eliminatePoint(app,z)
%             eliminatepoint = [245,432;244,432;592,162;432,244];
%             for m = 1:length(eliminatepoint)
%                 xp = eliminatepoint(m,1);
%                 yp = eliminatepoint(m,2);
%                 %[xp yp];
%                 z(yp,xp) = 0;
%             end
%         end


        function newZ = eliminatePoint(app,z)
            % X means damaged cell points in MCP.
            X = [57 272;
                125 202;
                162 591;
                162 592;
                286 3;
                353 376;
                432 244;
                432 245;
                450 134;];
            newZ = z;
            for k = 1:size(X,1)
                sq = z((X(k,1)-1) : (X(k,1)+1)  , (X(k,2)-1) : (X(k,2)+1));
                A = reshape(sq,1,[]);
                chuouchi = median(A);
                newZ(X(k,1),X(k,2))=chuouchi;
            end
        end
        
        function plotData(app)
            app.Data_z = dlmread(app.DataFileName);
            app.Data_z = eliminatePoint(app,app.Data_z);
            
            %             hold(app.UIAxes,'off');
            %             image(app.UIAxes,wl(app),time(app),app.Data_z,'CDataMapping','scaled');
            %             xlim(app.UIAxes,[-Inf Inf]);
            %             ylim(app.UIAxes,[-Inf Inf]);
            %             caxis(app.UIAxes,[-Inf max(max(app.Data_z))/2]);
            %             hold(app.UIAxes,'on');
            %             colormap(app.UIAxes,'hot');
            plotImage(app,app.UIAxes,app.Data_z);
        end
        
        
        function plotData_irf(app)
            app.Irf_z = dlmread(app.IrfFileName);
            app.Irf_z = eliminatePoint(app,app.Irf_z);
            %             hold(app.UIAxes_2,'off');
            %             image(app.UIAxes_2,wl(app),time(app),app.Irf_z,'CDataMapping','scaled');
            %             xlim(app.UIAxes_2,[-Inf Inf]);
            %             ylim(app.UIAxes_2,[-Inf Inf]);
            %             caxis(app.UIAxes_2,[-Inf max(max(app.Irf_z))/2]);
            %             hold(app.UIAxes_2,'on');
            %             colormap(app.UIAxes_2,'hot');
            plotImage(app,app.UIAxes_2,app.Irf_z);
        end
        
        function plotImage(app,ax,z)
            hold(ax,'off');
            if strcmp(app.ButtonGroup.SelectedObject.Text,'linear') % linear
                image(ax,wl(app),time(app),z,'CDataMapping','scaled');
                caxis(ax,[-Inf max(max(z))/2]);
            else % log plot
                image(ax,wl(app),time(app),log10(z),'CDataMapping','scaled');
                caxis(ax,[min(min(log10(z))) max(max(log10(z)))]);
            end
            xlim(ax,[-Inf Inf]);
            ylim(ax,[-Inf Inf]);
            
            hold(ax,'on');
            colormap(ax,'hot');
        end
        
        function plotROI(app)
            x1 = percentage2value(app.lowSlider.Value,wl(app));
            x2 = percentage2value(app.upSlider.Value,wl(app));
            y1 = percentage2valueT(app.Slider.Value,time(app));
            y2 = percentage2valueT(app.Slider_4.Value,time(app));
            app.WL_v2.Value = x2;
            app.WL_v1.Value = x1;
            app.Time_v2.Value = y2;
            app.Time_v1.Value = y1;
            ROI1 = [
                x1 y1;
                x2 y1;
                x2 y2;
                x1 y2;
                x1 y1];
            plot(app.UIAxes,ROI1(:,1),ROI1(:,2));
            
            function v = percentage2value(p,vector)
                v = ((100-p)*min(vector) + (p)*max(vector)) / 100;
            end
            function v = percentage2valueT(p,vector)
                v = ((p)*min(vector) + (100-p)*max(vector)) / 100;
            end
        end
        
        function plotROI_irf(app)
            x1 = percentage2value(app.lowSlider_2.Value,wl(app));
            x2 = percentage2value(app.upSlider_3.Value,wl(app));
            y1 = percentage2valueT(app.Slider_2.Value,time(app));
            y2 = percentage2valueT(app.Slider_3.Value,time(app));
            app.WL_v2_2.Value = x2;
            app.WL_v1_2.Value = x1;
            app.Time_v2_2.Value = y2;
            app.Time_v1_2.Value = y1;
            ROI_irf = [
                x1 y1;
                x2 y1;
                x2 y2;
                x1 y2;
                x1 y1];
            plot(app.UIAxes_2,ROI_irf(:,1),ROI_irf(:,2));
            
            function v = percentage2value(p,vector)
                v = ((100-p)*min(vector) + (p)*max(vector)) / 100;
            end
            function v = percentage2valueT(p,vector)
                v = ((p)*min(vector) + (100-p)*max(vector)) / 100;
            end
        end
        
        
        function wavelength = wl(app)
            % return wavelength vector
            center_wavelength = app.CenterWLnmEditField.Value;
            wavelength = linspace(507.8667, 794.3751, 640);
            wavelength = wavelength + center_wavelength - 650;
            
        end
        
        
        function t = time(app)
            val = app.TimeRangeDropDown.Value;
            val = strrep(val,' ns', '');
            val = strrep(val,' us', '');
            val = strrep(val, ' ms', '');
            suffix = app.TimeRangeDropDown.Value((end-1):end);
            if strcmp(suffix,'ns') == 1
                suf = 1;
            elseif strcmp(suffix,'us') == 1
                suf = 1000;
            elseif strcmp(suffix,'ms') == 1
                suf = 1000*1000;
            end
            
            input = suf * str2double(val);
            
            
            if input == 10
                t = linspace(0,9.966626,480);
                
            end
            if input == 20
                t = linspace(0,19.93412,480);
                
            end
            if input == 50
                t = linspace(0,50.0111,480);
                
            end
            if input == 100
                t = linspace(0,99.30154,480);
                
            end
            if input == 200
                t = linspace(0,200.0677,480);
                
            end
            if input == 500
                t = linspace(0,497.0368,480);
                
            end
            if input == 1000 %1us
                t = linspace(0,995.8607,480);
                
            end
            if input == 2000 %2us
                t = linspace(0,1988.502,480);
                
            end
            if input == 5000 %5us
                t = linspace(0,4973.798,480);
                
            end
            if input == 10000 %10us
                t = linspace(0,9979.353,480);
                
            end
            if input == 20000 %20us
                t = linspace(0,20007.89,480);
                
            end
            if input == 50000 %50us
                t = linspace(0,49839.37,480);
                
            end
            if input == 100000 %100us
                t = linspace(0,99606.21,480);
                
            end
            if input == 200000 %200us
                t = linspace(0,199314.7,480);
                
            end
            if input == 500000 %500us
                t = linspace(0,498722.9,480);
                
            end
            if input == 1000000 %1ms
                t = linspace(0,1002543,480);
                
            end
            if input == 2000000 %2ms
                t = linspace(0,1999257,480);
                
            end
            if input == 5000000 %5ms
                t = linspace(0,4979022,480);
                
            end
            if input == 10000000 %10ms
                t = linspace(0,9979160,480);
                
            end
        end


        function TableRenew(app)
            app.FitTable.Data = [app.taus];
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.taus = 20;
            TableRenew(app);
            
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            [fname,dirname] = uigetfile('*.dat');
            app.DataFileName = [dirname, '/', fname];
            Ax1(app);
        end

        % Value changed function: CenterWLnmEditField
        function CenterWLnmEditFieldValueChanged(app, event)
            
            Ax1(app);
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            Ax1(app);
        end

        % Value changing function: Slider_4
        function Slider_4ValueChanging(app, event)
            changingValue = event.Value;
            Ax1(app);
        end

        % Value changing function: upSlider
        function upSliderValueChanging(app, event)
            changingValue = event.Value;
            Ax1(app);
        end

        % Value changing function: lowSlider
        function lowSliderValueChanging(app, event)
            changingValue = event.Value;
            Ax1(app);
        end

        % Value changed function: upSlider
        function upSliderValueChanged(app, event)
            value = app.upSlider.Value;
            Ax1(app);
        end

        % Value changed function: lowSlider
        function lowSliderValueChanged(app, event)
            value = app.lowSlider.Value;
            Ax1(app);
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            Ax1(app);
        end

        % Value changed function: Slider_4
        function Slider_4ValueChanged(app, event)
            value = app.Slider_4.Value;
            Ax1(app);
        end

        % Value changing function: Slider_3
        function Slider_3ValueChanging(app, event)
            changingValue = event.Value;
            Ax2(app);
        end

        % Button pushed function: IRFButton
        function IRFButtonPushed(app, event)
            [fname,dirname] = uigetfile('*.dat');
            app.IrfFileName = [dirname, '/', fname];
            
            Ax2(app);
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            value = app.Slider_2.Value;
            Ax2(app);
        end

        % Value changing function: Slider_2
        function Slider_2ValueChanging(app, event)
            changingValue = event.Value;
            Ax2(app);
        end

        % Value changed function: Slider_3
        function Slider_3ValueChanged(app, event)
            value = app.Slider_3.Value;
            Ax2(app);
        end

        % Value changed function: upSlider_3
        function upSlider_3ValueChanged(app, event)
            value = app.upSlider_3.Value;
            Ax2(app);
        end

        % Value changing function: upSlider_3
        function upSlider_3ValueChanging(app, event)
            changingValue = event.Value;
            Ax2(app);
        end

        % Value changed function: lowSlider_2
        function lowSlider_2ValueChanged(app, event)
            value = app.lowSlider_2.Value;
            Ax2(app);
        end

        % Value changing function: lowSlider_2
        function lowSlider_2ValueChanging(app, event)
            changingValue = event.Value;
            Ax2(app);
        end

        % Value changed function: Time_v1_2
        function Time_v1_2ValueChanged(app, event)
            value = app.Time_v1_2.Value;
            x = time(app);
            n = (value - max(x))/(min(x) - max(x))*100;
            app.Slider_2.Value = n;
            Ax2(app);
        end

        % Value changed function: Time_v2_2
        function Time_v2_2ValueChanged(app, event)
            value = app.Time_v2_2.Value;
            x = time(app);
            n = (value - max(x))/(min(x) - max(x))*100;
            app.Slider_3.Value = n;
            Ax2(app);
        end

        % Value changed function: Time_v1
        function Time_v1ValueChanged(app, event)
            value = app.Time_v1.Value;
            x = time(app);
            n = (value - max(x))/(min(x) - max(x))*100;
            app.Slider.Value = n;
            Ax1(app);
        end

        % Value changed function: Time_v2
        function Time_v2ValueChanged(app, event)
            value = app.Time_v2.Value;
            x = time(app);
            n = (value - max(x))/(min(x) - max(x))*100;
            app.Slider_4.Value = n;
            Ax1(app);
        end

        % Value changed function: WL_v2
        function WL_v2ValueChanged(app, event)
            value = app.WL_v2.Value;
            x = wl(app);
            n = 100-(value - max(x))/(min(x) - max(x))*100;
            app.upSlider.Value = n;
            Ax1(app);
        end

        % Value changed function: WL_v1
        function WL_v1ValueChanged(app, event)
            value = app.WL_v1.Value;
            x = wl(app);
            n = 100-(value - max(x))/(min(x) - max(x))*100;
            app.lowSlider.Value = n;
            Ax1(app);
        end

        % Value changed function: WL_v2_2
        function WL_v2_2ValueChanged(app, event)
            value = app.WL_v2_2.Value;
            x = wl(app);
            n = 100-(value - max(x))/(min(x) - max(x))*100;
            app.upSlider_3.Value = n;
            Ax2(app);
        end

        % Value changed function: WL_v1_2
        function WL_v1_2ValueChanged(app, event)
            value = app.WL_v1_2.Value;
            
            x = wl(app);
            n = 100-(value - max(x))/(min(x) - max(x))*100;
            app.lowSlider_2.Value = n;
            Ax2(app);
        end

        % Value changed function: NooftauSpinner
        function NooftauSpinnerValueChanged(app, event)
            value = app.NooftauSpinner.Value;
            if length(app.taus)>value
                app.taus(end) = [];
            elseif length(app.taus)<value
                app.taus = [app.taus;rand(1)*max(time(app))];
            end
            TableRenew(app);
            Ax3(app);
        end

        % Cell edit callback: FitTable
        function FitTableCellEdit(app, event)
            app.taus = app.FitTable.Data;
            Ax3(app);
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            if isempty(app.FitData)
                % before fitting, don't work
            else
                [FileName,PathName] = uiputfile('*dat','save fit results',[strrep(app.DataFileName,'.dat',''), '-fit.dat']);
                if FileName ~= 0
                    % data set for origin
                    fullpath = [PathName, '/', FileName];
                    writetable(app.FitData,fullpath,'FileType','text');
                    
                    % parameters
                    best = app.bestTable.Data;
                    outputstr = cell(size(best,1)+2,2);
                    for k = 1:size(best,1)
                        outputstr{2*k-1,1} = ['tau', num2str(k)];
                        outputstr{2*k,1} = ['amp', num2str(k)];
                        outputstr{2*k-1,2} = mat2str(best(k,1));
                        outputstr{2*k,2} = mat2str(best(k,2));
                    end
                    kend = size(best,1)*2;
                    outputstr{kend+1,2} = mat2str(sort([app.WL_v2.Value, app.WL_v1.Value]));
                    outputstr{kend+1,1} = 'wlRange';
                    outputstr{kend+2,2} = mat2str(sort([app.Time_v2.Value, app.Time_v1.Value]));
                    outputstr{kend+2,1} = 'timeRange';
                    
                    pfilename = strrep(fullpath,'-fit.dat','-fitpar.txt');
                    T = cell2table(outputstr,'VariableNames',{'parameter','value'});
                    writetable(T,pfilename);
                    
                end
                
            end
        end

        % Value changed function: y_offset
        function y_offsetValueChanged(app, event)
            value = app.y_offset.Value;
            Ax3(app);
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            app.DataFileName
            if isempty(app.DataFileName) == 0
                Ax1(app);
            end
            if isempty(app.IrfFileName) == 0
                Ax2(app);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Data')
            xlabel(app.UIAxes, 'wavelength (nm)')
            ylabel(app.UIAxes, 'time (ns)')
            app.upSliderLabel = uilabel(app.UIFigure);
            app.upSliderLabel.Text = 'up';
            app.upSlider = uislider(app.UIFigure);
            app.upSlider.MajorTickLabels = {'', '', '', '', '', '', '', '', '', '', ''};
            app.upSlider.ValueChangedFcn = createCallbackFcn(app, @upSliderValueChanged, true);
            app.upSlider.ValueChangingFcn = createCallbackFcn(app, @upSliderValueChanging, true);
            app.upSlider.Value = 100;
            app.lowSliderLabel = uilabel(app.UIFigure);
            app.lowSliderLabel.Text = 'low';
            app.lowSlider = uislider(app.UIFigure);
            app.lowSlider.MajorTicks = [];
            app.lowSlider.MajorTickLabels = {};
            app.lowSlider.ValueChangedFcn = createCallbackFcn(app, @lowSliderValueChanged, true);
            app.lowSlider.ValueChangingFcn = createCallbackFcn(app, @lowSliderValueChanging, true);
            app.lowSlider.MinorTicks = [];
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.WL_v2 = uieditfield(app.UIFigure, 'numeric');
            app.WL_v2.ValueChangedFcn = createCallbackFcn(app, @WL_v2ValueChanged, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.WL_v1 = uieditfield(app.UIFigure, 'numeric');
            app.WL_v1.ValueChangedFcn = createCallbackFcn(app, @WL_v1ValueChanged, true);
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = ' ';
            app.Slider = uislider(app.UIFigure);
            app.Slider.MajorTickLabels = {'', '', '', '', '', ''};
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider_4Label = uilabel(app.UIFigure);
            app.Slider_4Label.Text = '';
            app.Slider_4 = uislider(app.UIFigure);
            app.Slider_4.MajorTicks = [];
            app.Slider_4.MajorTickLabels = {};
            app.Slider_4.ValueChangedFcn = createCallbackFcn(app, @Slider_4ValueChanged, true);
            app.Slider_4.ValueChangingFcn = createCallbackFcn(app, @Slider_4ValueChanging, true);
            app.Slider_4.MinorTicks = [];
            app.Slider_4.Value = 100;
            app.ImportButton = uibutton(app.UIFigure, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.CenterWLnmEditFieldLabel = uilabel(app.UIFigure);
            app.CenterWLnmEditFieldLabel.Text = 'Center WL (nm)';
            app.CenterWLnmEditField = uieditfield(app.UIFigure, 'numeric');
            app.CenterWLnmEditField.Limits = [0 1000];
            app.CenterWLnmEditField.ValueChangedFcn = createCallbackFcn(app, @CenterWLnmEditFieldValueChanged, true);
            app.CenterWLnmEditField.Value = 650;
            app.EditField_3Label = uilabel(app.UIFigure);
            app.EditField_3Label.Text = '';
            app.Time_v2 = uieditfield(app.UIFigure, 'numeric');
            app.Time_v2.ValueChangedFcn = createCallbackFcn(app, @Time_v2ValueChanged, true);
            app.EditField_4Label = uilabel(app.UIFigure);
            app.EditField_4Label.Text = '';
            app.Time_v1 = uieditfield(app.UIFigure, 'numeric');
            app.Time_v1.ValueChangedFcn = createCallbackFcn(app, @Time_v1ValueChanged, true);
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'IRF')
            xlabel(app.UIAxes_2, 'wavelength (nm)')
            ylabel(app.UIAxes_2, 'time (ns)')
            app.IRFButton = uibutton(app.UIFigure, 'push');
            app.IRFButton.ButtonPushedFcn = createCallbackFcn(app, @IRFButtonPushed, true);
            app.IRFButton.Text = 'IRF';
            app.upSlider_3Label = uilabel(app.UIFigure);
            app.upSlider_3Label.Text = 'up';
            app.upSlider_3 = uislider(app.UIFigure);
            app.upSlider_3.MajorTicks = [0 25 50 75 100];
            app.upSlider_3.MajorTickLabels = {'', '', '', '', ''};
            app.upSlider_3.ValueChangedFcn = createCallbackFcn(app, @upSlider_3ValueChanged, true);
            app.upSlider_3.ValueChangingFcn = createCallbackFcn(app, @upSlider_3ValueChanging, true);
            app.upSlider_3.Value = 100;
            app.lowSlider_2Label = uilabel(app.UIFigure);
            app.lowSlider_2Label.Text = 'low';
            app.lowSlider_2 = uislider(app.UIFigure);
            app.lowSlider_2.MajorTicks = [];
            app.lowSlider_2.MajorTickLabels = {};
            app.lowSlider_2.ValueChangedFcn = createCallbackFcn(app, @lowSlider_2ValueChanged, true);
            app.lowSlider_2.ValueChangingFcn = createCallbackFcn(app, @lowSlider_2ValueChanging, true);
            app.lowSlider_2.MinorTicks = [];
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.WL_v2_2 = uieditfield(app.UIFigure, 'numeric');
            app.WL_v2_2.ValueChangedFcn = createCallbackFcn(app, @WL_v2_2ValueChanged, true);
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '';
            app.WL_v1_2 = uieditfield(app.UIFigure, 'numeric');
            app.WL_v1_2.ValueChangedFcn = createCallbackFcn(app, @WL_v1_2ValueChanged, true);
            app.Slider_2Label = uilabel(app.UIFigure);
            app.Slider_2Label.Text = ' ';
            app.Slider_2 = uislider(app.UIFigure);
            app.Slider_2.MajorTickLabels = {'', '', '', '', '', ''};
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Slider_2.ValueChangingFcn = createCallbackFcn(app, @Slider_2ValueChanging, true);
            app.Slider_3Label = uilabel(app.UIFigure);
            app.Slider_3Label.Text = ' ';
            app.Slider_3 = uislider(app.UIFigure);
            app.Slider_3.MajorTicks = [];
            app.Slider_3.MajorTickLabels = {};
            app.Slider_3.ValueChangedFcn = createCallbackFcn(app, @Slider_3ValueChanged, true);
            app.Slider_3.ValueChangingFcn = createCallbackFcn(app, @Slider_3ValueChanging, true);
            app.Slider_3.MinorTicks = [];
            app.Slider_3.Value = 100;
            app.EditField_3Label_2 = uilabel(app.UIFigure);
            app.EditField_3Label_2.Text = '';
            app.Time_v2_2 = uieditfield(app.UIFigure, 'numeric');
            app.Time_v2_2.ValueChangedFcn = createCallbackFcn(app, @Time_v2_2ValueChanged, true);
            app.EditField_4Label_2 = uilabel(app.UIFigure);
            app.EditField_4Label_2.Text = '';
            app.Time_v1_2 = uieditfield(app.UIFigure, 'numeric');
            app.Time_v1_2.ValueChangedFcn = createCallbackFcn(app, @Time_v1_2ValueChanged, true);
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Time profile')
            xlabel(app.UIAxes3, 'Time (ns)')
            ylabel(app.UIAxes3, 'PL intensity')
            app.FitTable = uitable(app.UIFigure);
            app.FitTable.ColumnName = {'Ini. tau'};
            app.FitTable.RowName = {};
            app.FitTable.ColumnEditable = true;
            app.FitTable.CellEditCallback = createCallbackFcn(app, @FitTableCellEdit, true);
            app.NooftauSpinnerLabel = uilabel(app.UIFigure);
            app.NooftauSpinnerLabel.Text = 'No. of tau';
            app.NooftauSpinner = uispinner(app.UIFigure);
            app.NooftauSpinner.Limits = [1 Inf];
            app.NooftauSpinner.ValueChangedFcn = createCallbackFcn(app, @NooftauSpinnerValueChanged, true);
            app.NooftauSpinner.Value = 1;
            app.bestTable = uitable(app.UIFigure);
            app.bestTable.ColumnName = {'best tau'; 'best amp'};
            app.bestTable.RowName = {};
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.TimeRangeDropDownLabel = uilabel(app.UIFigure);
            app.TimeRangeDropDownLabel.Text = 'Time Range';
            app.TimeRangeDropDown = uidropdown(app.UIFigure);
            app.TimeRangeDropDown.Items = {'10 ns', '20 ns', '50 ns', '100 ns', '200 ns', '200 ns', '500 ns', '1 us', '2 us', '5 us', '10 us', '20 us', '50 us', '100 us', '200 us', '500 us', '1 ms', '2 ms', '5 ms', '10 ms'};
            app.TimeRangeDropDown.Value = '50 ns';
            app.Slider_5Label = uilabel(app.UIFigure);
            app.Slider_5Label.Text = '';
            app.y_offset = uislider(app.UIFigure);
            app.y_offset.MajorTicks = [];
            app.y_offset.MajorTickLabels = {};
            app.y_offset.ValueChangedFcn = createCallbackFcn(app, @y_offsetValueChanged, true);
            app.y_offset.MinorTicks = [];
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.linearButton = uiradiobutton(app.ButtonGroup);
            app.linearButton.Text = 'linear';
            app.linearButton.Value = true;
            app.log10Button = uiradiobutton(app.ButtonGroup);
            app.log10Button.Text = 'log10';
        end
    end

    methods (Access = public)

        % Construct app
        function app = PL_FitApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end