classdef fluorescence_FittingApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        upSliderLabel             matlab.ui.control.Label
        upSlider                  matlab.ui.control.Slider
        lowSliderLabel            matlab.ui.control.Label
        lowSlider                 matlab.ui.control.Slider
        Label_2                   matlab.ui.control.Label
        WL_v2                     matlab.ui.control.NumericEditField
        Label                     matlab.ui.control.Label
        WL_v1                     matlab.ui.control.NumericEditField
        SliderLabel               matlab.ui.control.Label
        Slider                    matlab.ui.control.Slider
        upSlider_2Label           matlab.ui.control.Label
        upSlider_2                matlab.ui.control.Slider
        ImportButton              matlab.ui.control.Button
        CenterWLnmEditFieldLabel  matlab.ui.control.Label
        CenterWLnmEditField       matlab.ui.control.NumericEditField
        TimeRangensSpinnerLabel   matlab.ui.control.Label
        TimeRangensSpinner        matlab.ui.control.Spinner
        EditField_3Label          matlab.ui.control.Label
        Time_v2                   matlab.ui.control.NumericEditField
        EditField_4Label          matlab.ui.control.Label
        Time_v1                   matlab.ui.control.NumericEditField
        UIAxes_2                  matlab.ui.control.UIAxes
        IRFButton                 matlab.ui.control.Button
        upSlider_3Label           matlab.ui.control.Label
        upSlider_3                matlab.ui.control.Slider
        lowSlider_2Label          matlab.ui.control.Label
        lowSlider_2               matlab.ui.control.Slider
        Label_3                   matlab.ui.control.Label
        WL_v2_2                   matlab.ui.control.NumericEditField
        Label_4                   matlab.ui.control.Label
        WL_v1_2                   matlab.ui.control.NumericEditField
        Slider_2Label             matlab.ui.control.Label
        Slider_2                  matlab.ui.control.Slider
        upSlider_4Label           matlab.ui.control.Label
        upSlider_4                matlab.ui.control.Slider
        EditField_3Label_2        matlab.ui.control.Label
        Time_v2_2                 matlab.ui.control.NumericEditField
        EditField_4Label_2        matlab.ui.control.Label
        Time_v1_2                 matlab.ui.control.NumericEditField
        UIAxes3                   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        DataFileName % filename of data
        IrfFileName % instrumental function of data
        Data_z % experimental data
        Irf_z % irf data
    end
    
    methods (Access = public)
        function Ax1(app)
            plotData(app);
            plotROI(app);
        end
        
        function Ax2(app)
            plotData_irf(app);
            plotROI_irf(app);
        end
        
        function Ax3(app)
            
        end
        function plotData(app)
            app.Data_z = dlmread(app.DataFileName);
            hold(app.UIAxes,'off');
            image(app.UIAxes,wl(app),time(app),app.Data_z,'CDataMapping','scaled');
            xlim(app.UIAxes,[-Inf Inf]);
            ylim(app.UIAxes,[-Inf Inf]);
            caxis(app.UIAxes,[-Inf max(max(app.Data_z))/2]);
            hold(app.UIAxes,'on');
            colormap(app.UIAxes,'hot');
        end
        
        
        function plotData_irf(app)
            
            app.Data_z = dlmread(app.IrfFileName);
            hold(app.UIAxes_2,'off');
            image(app.UIAxes_2,wl(app),time(app),app.Data_z,'CDataMapping','scaled');
            xlim(app.UIAxes_2,[-Inf Inf]);
            ylim(app.UIAxes_2,[-Inf Inf]);
            caxis(app.UIAxes_2,[-Inf max(max(app.Data_z))/2]);
            hold(app.UIAxes_2,'on');
            colormap(app.UIAxes_2,'hot');
        end
        
        function plotROI(app)
            x1 = percentage2value(app.lowSlider.Value,wl(app));
            x2 = percentage2value(app.upSlider.Value,wl(app));
            y1 = percentage2valueT(app.Slider.Value,time(app));
            y2 = percentage2valueT(app.upSlider_2.Value,time(app));
            app.WL_v2.Value = x2;
            app.WL_v1.Value = x1;
            app.Time_v2.Value = y2;
            app.Time_v1.Value = y1;
            ROI1 = [
                x1 y1;
                x2 y1;
                x2 y2;
                x1 y2;
                x1 y1];
            plot(app.UIAxes,ROI1(:,1),ROI1(:,2));
            
            function v = percentage2value(p,vector)
                v = ((100-p)*min(vector) + (p)*max(vector)) / 100;
            end
            function v = percentage2valueT(p,vector)
                v = ((p)*min(vector) + (100-p)*max(vector)) / 100;
            end
        end
        
        function plotROI_irf(app)
            x1 = percentage2value(app.lowSlider_2.Value,wl(app));
            x2 = percentage2value(app.upSlider_3.Value,wl(app));
            y1 = percentage2valueT(app.Slider_2.Value,time(app));
            y2 = percentage2valueT(app.upSlider_4.Value,time(app));
            app.WL_v2_2.Value = x2;
            app.WL_v1_2.Value = x1;
            app.Time_v2_2.Value = y2;
            app.Time_v1_2.Value = y1;
            ROI_irf = [
                x1 y1;
                x2 y1;
                x2 y2;
                x1 y2;
                x1 y1];
            plot(app.UIAxes_2,ROI_irf(:,1),ROI_irf(:,2));
            
            function v = percentage2value(p,vector)
                v = ((100-p)*min(vector) + (p)*max(vector)) / 100;
            end
            function v = percentage2valueT(p,vector)
                v = ((p)*min(vector) + (100-p)*max(vector)) / 100;
            end
        end
        
        
        function wavelength = wl(app)
            % return wavelength vector
            center_wavelength = app.CenterWLnmEditField.Value;
            wavelength = linspace(507.8667, 794.3751, 640);
            wavelength = wavelength + center_wavelength - 650;

        end
        
        function t = time(app)
            % return time vector
            t = linspace(0, 50.0111, 480);
        end
        function defineSliderValue(app)
            t = time(app);
            wavelength = wl(app);
        end


        function y = principle_exponential(tau,time)
            % tau = [tau1, tau2, tau3,...]
            % y = [y1; y2; y3;...];
            % y(n,:) = exp(-t/tau(n))
            
            t = reshape(time,1,[]);
            
            taus = reshape(tau,1,[]);
            [TAU, T] = ndgrid(taus,t);
            x = -T./TAU;
            y = exp(x);
            y(:,t<0) = 0;
        end
        
        
    end
    

    methods (Access = private)

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            app.DataFileName = uigetfile('*.dat');
            Ax1(app);
        end

        % Value changed function: CenterWLnmEditField
        function CenterWLnmEditFieldValueChanged(app, event)
            
            Ax1(app);
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            Ax1(app);
        end

        % Value changing function: upSlider_2
        function upSlider_2ValueChanging(app, event)
            changingValue = event.Value;
            Ax1(app);
        end

        % Value changing function: upSlider
        function upSliderValueChanging(app, event)
            changingValue = event.Value;
            Ax1(app);
        end

        % Value changing function: lowSlider
        function lowSliderValueChanging(app, event)
            changingValue = event.Value;
            Ax1(app);
        end

        % Value changed function: upSlider
        function upSliderValueChanged(app, event)
            value = app.upSlider.Value;
            Ax1(app);
        end

        % Value changed function: lowSlider
        function lowSliderValueChanged(app, event)
            value = app.lowSlider.Value;
            Ax1(app);
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            Ax1(app);
        end

        % Value changed function: upSlider_2
        function upSlider_2ValueChanged(app, event)
            value = app.upSlider_2.Value;
            Ax1(app);
        end

        % Value changing function: upSlider_4
        function upSlider_4ValueChanging(app, event)
            changingValue = event.Value;
            Ax2(app);
        end

        % Button pushed function: IRFButton
        function IRFButtonPushed(app, event)
            app.IrfFileName = uigetfile('*.dat');
            Ax2(app);
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            value = app.Slider_2.Value;
            Ax2(app);
        end

        % Value changing function: Slider_2
        function Slider_2ValueChanging(app, event)
            changingValue = event.Value;
            Ax2(app);
        end

        % Value changed function: upSlider_4
        function upSlider_4ValueChanged(app, event)
            value = app.upSlider_4.Value;
            Ax2(app);
        end

        % Value changed function: upSlider_3
        function upSlider_3ValueChanged(app, event)
            value = app.upSlider_3.Value;
            Ax2(app);
        end

        % Value changing function: upSlider_3
        function upSlider_3ValueChanging(app, event)
            changingValue = event.Value;
            Ax2(app);
        end

        % Value changed function: lowSlider_2
        function lowSlider_2ValueChanged(app, event)
            value = app.lowSlider_2.Value;
            Ax2(app);
        end

        % Value changing function: lowSlider_2
        function lowSlider_2ValueChanging(app, event)
            changingValue = event.Value;
            Ax2(app);
        end

        % Value changed function: Time_v1_2
        function Time_v1_2ValueChanged(app, event)
            value = app.Time_v1_2.Value;
            x = time(app);
            n = (value - max(x))/(min(x) - max(x))*100;
            app.Slider_2.Value = n;
            Ax2(app);
        end

        % Value changed function: Time_v2_2
        function Time_v2_2ValueChanged(app, event)
            value = app.Time_v2_2.Value;
            x = time(app);
            n = (value - max(x))/(min(x) - max(x))*100;
            app.upSlider_4.Value = n;
            Ax2(app);
        end

        % Value changed function: Time_v1
        function Time_v1ValueChanged(app, event)
            value = app.Time_v1.Value;
            x = time(app);
            n = (value - max(x))/(min(x) - max(x))*100;
            app.Slider.Value = n;
            Ax1(app);
        end

        % Value changed function: Time_v2
        function Time_v2ValueChanged(app, event)
            value = app.Time_v2.Value;
            x = time(app);
            n = (value - max(x))/(min(x) - max(x))*100;
            app.upSlider_2.Value = n;
            Ax1(app);
        end

        % Value changed function: WL_v2
        function WL_v2ValueChanged(app, event)
            value = app.WL_v2.Value;
            x = wl(app);
            n = 100-(value - max(x))/(min(x) - max(x))*100;
            app.upSlider.Value = n;
            Ax1(app);
        end

        % Value changed function: WL_v1
        function WL_v1ValueChanged(app, event)
            value = app.WL_v1.Value;
            x = wl(app);
            n = 100-(value - max(x))/(min(x) - max(x))*100;
            app.lowSlider.Value = n;
            Ax1(app);
        end

        % Value changed function: WL_v2_2
        function WL_v2_2ValueChanged(app, event)
            value = app.WL_v2_2.Value;
            x = wl(app);
            n = 100-(value - max(x))/(min(x) - max(x))*100;
            app.upSlider_3.Value = n;
            Ax2(app);
        end

        % Value changed function: WL_v1_2
        function WL_v1_2ValueChanged(app, event)
            value = app.WL_v1_2.Value;
    
            x = wl(app);
            n = 100-(value - max(x))/(min(x) - max(x))*100;
            app.lowSlider_2.Value = n;
            Ax2(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Data')
            xlabel(app.UIAxes, 'wavelength (nm)')
            ylabel(app.UIAxes, 'time (ns)')
            app.upSliderLabel = uilabel(app.UIFigure);
            app.upSliderLabel.Text = 'up';
            app.upSlider = uislider(app.UIFigure);
            app.upSlider.ValueChangedFcn = createCallbackFcn(app, @upSliderValueChanged, true);
            app.upSlider.ValueChangingFcn = createCallbackFcn(app, @upSliderValueChanging, true);
            app.upSlider.Value = 100;
            app.lowSliderLabel = uilabel(app.UIFigure);
            app.lowSliderLabel.Text = 'low';
            app.lowSlider = uislider(app.UIFigure);
            app.lowSlider.ValueChangedFcn = createCallbackFcn(app, @lowSliderValueChanged, true);
            app.lowSlider.ValueChangingFcn = createCallbackFcn(app, @lowSliderValueChanging, true);
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.WL_v2 = uieditfield(app.UIFigure, 'numeric');
            app.WL_v2.ValueChangedFcn = createCallbackFcn(app, @WL_v2ValueChanged, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.WL_v1 = uieditfield(app.UIFigure, 'numeric');
            app.WL_v1.ValueChangedFcn = createCallbackFcn(app, @WL_v1ValueChanged, true);
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = ' ';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.upSlider_2Label = uilabel(app.UIFigure);
            app.upSlider_2Label.Text = 'up';
            app.upSlider_2 = uislider(app.UIFigure);
            app.upSlider_2.ValueChangedFcn = createCallbackFcn(app, @upSlider_2ValueChanged, true);
            app.upSlider_2.ValueChangingFcn = createCallbackFcn(app, @upSlider_2ValueChanging, true);
            app.upSlider_2.Value = 100;
            app.ImportButton = uibutton(app.UIFigure, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.CenterWLnmEditFieldLabel = uilabel(app.UIFigure);
            app.CenterWLnmEditFieldLabel.Text = 'Center WL (nm)';
            app.CenterWLnmEditField = uieditfield(app.UIFigure, 'numeric');
            app.CenterWLnmEditField.Limits = [0 1000];
            app.CenterWLnmEditField.ValueChangedFcn = createCallbackFcn(app, @CenterWLnmEditFieldValueChanged, true);
            app.CenterWLnmEditField.Value = 650;
            app.TimeRangensSpinnerLabel = uilabel(app.UIFigure);
            app.TimeRangensSpinnerLabel.Text = 'Time Range (ns)';
            app.TimeRangensSpinner = uispinner(app.UIFigure);
            app.EditField_3Label = uilabel(app.UIFigure);
            app.EditField_3Label.Text = '';
            app.Time_v2 = uieditfield(app.UIFigure, 'numeric');
            app.Time_v2.ValueChangedFcn = createCallbackFcn(app, @Time_v2ValueChanged, true);
            app.EditField_4Label = uilabel(app.UIFigure);
            app.EditField_4Label.Text = '';
            app.Time_v1 = uieditfield(app.UIFigure, 'numeric');
            app.Time_v1.ValueChangedFcn = createCallbackFcn(app, @Time_v1ValueChanged, true);
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'IRF')
            xlabel(app.UIAxes_2, 'wavelength (nm)')
            ylabel(app.UIAxes_2, 'time (ns)')
            app.IRFButton = uibutton(app.UIFigure, 'push');
            app.IRFButton.ButtonPushedFcn = createCallbackFcn(app, @IRFButtonPushed, true);
            app.IRFButton.Text = 'IRF';
            app.upSlider_3Label = uilabel(app.UIFigure);
            app.upSlider_3Label.Text = 'up';
            app.upSlider_3 = uislider(app.UIFigure);
            app.upSlider_3.ValueChangedFcn = createCallbackFcn(app, @upSlider_3ValueChanged, true);
            app.upSlider_3.ValueChangingFcn = createCallbackFcn(app, @upSlider_3ValueChanging, true);
            app.upSlider_3.Value = 100;
            app.lowSlider_2Label = uilabel(app.UIFigure);
            app.lowSlider_2Label.Text = 'low';
            app.lowSlider_2 = uislider(app.UIFigure);
            app.lowSlider_2.ValueChangedFcn = createCallbackFcn(app, @lowSlider_2ValueChanged, true);
            app.lowSlider_2.ValueChangingFcn = createCallbackFcn(app, @lowSlider_2ValueChanging, true);
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.WL_v2_2 = uieditfield(app.UIFigure, 'numeric');
            app.WL_v2_2.ValueChangedFcn = createCallbackFcn(app, @WL_v2_2ValueChanged, true);
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '';
            app.WL_v1_2 = uieditfield(app.UIFigure, 'numeric');
            app.WL_v1_2.ValueChangedFcn = createCallbackFcn(app, @WL_v1_2ValueChanged, true);
            app.Slider_2Label = uilabel(app.UIFigure);
            app.Slider_2Label.Text = ' ';
            app.Slider_2 = uislider(app.UIFigure);
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Slider_2.ValueChangingFcn = createCallbackFcn(app, @Slider_2ValueChanging, true);
            app.upSlider_4Label = uilabel(app.UIFigure);
            app.upSlider_4Label.Text = 'up';
            app.upSlider_4 = uislider(app.UIFigure);
            app.upSlider_4.ValueChangedFcn = createCallbackFcn(app, @upSlider_4ValueChanged, true);
            app.upSlider_4.ValueChangingFcn = createCallbackFcn(app, @upSlider_4ValueChanging, true);
            app.upSlider_4.Value = 100;
            app.EditField_3Label_2 = uilabel(app.UIFigure);
            app.EditField_3Label_2.Text = '';
            app.Time_v2_2 = uieditfield(app.UIFigure, 'numeric');
            app.Time_v2_2.ValueChangedFcn = createCallbackFcn(app, @Time_v2_2ValueChanged, true);
            app.EditField_4Label_2 = uilabel(app.UIFigure);
            app.EditField_4Label_2.Text = '';
            app.Time_v1_2 = uieditfield(app.UIFigure, 'numeric');
            app.Time_v1_2.ValueChangedFcn = createCallbackFcn(app, @Time_v1_2ValueChanged, true);
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Time profile')
            xlabel(app.UIAxes3, 'Time (ns)')
            ylabel(app.UIAxes3, 'PL intensity')
        end
    end

    methods (Access = public)

        % Construct app
        function app = fluorescence_FittingApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end